classdef prueba < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PnduloInvertidoUIFigure         matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        PosicincarritoTab               matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        VelocidadcarritoTab             matlab.ui.container.Tab
        UIAxes2                         matlab.ui.control.UIAxes
        ngulopnduloTab                  matlab.ui.container.Tab
        UIAxes3                         matlab.ui.control.UIAxes
        VelocidadpnduloTab              matlab.ui.container.Tab
        UIAxes4                         matlab.ui.control.UIAxes
        GuardardatosTab                 matlab.ui.container.Tab
        RutadelarchivoEditFieldLabel    matlab.ui.control.Label
        RutadelarchivoEditField         matlab.ui.control.EditField
        GuardarButton                   matlab.ui.control.Button
        NombredelarchivoEditFieldLabel  matlab.ui.control.Label
        NombredelarchivoEditField       matlab.ui.control.EditField
        NOTALosdatossonguardadosenunarchivoconextensinCSVLabel  matlab.ui.control.Label
        SeleccionarButton               matlab.ui.control.Button
        RunButton                       matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
            %Guardar datos en csv
            try
                filename = app.RutadelarchivoEditField.Value + "\" + app.NombredelarchivoEditField.Value + ".csv";
                run("modelado_pendulo_estados.m")
                varNames = {'Tiempo','Posicion carrito','Velocidad carrito','Angulo pendulo','Velocidad pendulo'};
                contenido = table(t,posicion_carrito,velocidad_carrito,angulo_pendulo,velocidad_pendulo,'VariableNames',varNames);
                try
                    writetable(contenido, filename)
                    msgbox("Los datos han sido guardados con éxito","Aviso")
                catch
                    errordlg("Oops! Parece que hay un problema.","Error");
                end
            catch
            end
        end

        % Button pushed function: SeleccionarButton
        function SeleccionarButtonPushed(app, event)
            %Seleccionar ruta
            path = uigetdir('C:\');
            app.RutadelarchivoEditField.Value = path;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            run("modelado_pendulo_estados.m")
            plot(app.UIAxes,t,posicion_carrito,'Color','[1 0 0]');
            plot(app.UIAxes2,t,velocidad_carrito,'Color','[0 0 1]');
            plot(app.UIAxes3,t,angulo_pendulo,'Color','[1 0 0]');
            plot(app.UIAxes4,t,velocidad_pendulo,'Color','[0 0 1]');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PnduloInvertidoUIFigure = uifigure('Visible', 'off');
            app.PnduloInvertidoUIFigure.Name = 'Péndulo Invertido';
            app.TabGroup = uitabgroup(app.PnduloInvertidoUIFigure);
            app.PosicincarritoTab = uitab(app.TabGroup);
            app.PosicincarritoTab.Title = 'Posición carrito';
            app.UIAxes = uiaxes(app.PosicincarritoTab);
            title(app.UIAxes, 'Posición del carrito [m]')
            xlabel(app.UIAxes, 't [s]')
            ylabel(app.UIAxes, 'X [m]')
            app.VelocidadcarritoTab = uitab(app.TabGroup);
            app.VelocidadcarritoTab.Title = 'Velocidad carrito';
            app.UIAxes2 = uiaxes(app.VelocidadcarritoTab);
            title(app.UIAxes2, 'Velocidad del carrito [m]')
            xlabel(app.UIAxes2, 't [s]')
            ylabel(app.UIAxes2, 'v [m/s]')
            app.ngulopnduloTab = uitab(app.TabGroup);
            app.ngulopnduloTab.Title = 'Ángulo péndulo';
            app.UIAxes3 = uiaxes(app.ngulopnduloTab);
            title(app.UIAxes3, 'Posición del péndulo [°]')
            xlabel(app.UIAxes3, 't [s]')
            ylabel(app.UIAxes3, 'Ángulo[°]')
            app.VelocidadpnduloTab = uitab(app.TabGroup);
            app.VelocidadpnduloTab.Title = 'Velocidad péndulo';
            app.UIAxes4 = uiaxes(app.VelocidadpnduloTab);
            title(app.UIAxes4, 'Velocidad del péndulo [rad/s]')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'v [rad/s]')
            app.GuardardatosTab = uitab(app.TabGroup);
            app.GuardardatosTab.Title = 'Guardar datos';
            app.RutadelarchivoEditFieldLabel = uilabel(app.GuardardatosTab);
            app.RutadelarchivoEditFieldLabel.Text = 'Ruta del archivo';
            app.RutadelarchivoEditField = uieditfield(app.GuardardatosTab, 'text');
            app.GuardarButton = uibutton(app.GuardardatosTab, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Text = 'Guardar';
            app.NombredelarchivoEditFieldLabel = uilabel(app.GuardardatosTab);
            app.NombredelarchivoEditFieldLabel.Text = 'Nombre del archivo';
            app.NombredelarchivoEditField = uieditfield(app.GuardardatosTab, 'text');
            app.NOTALosdatossonguardadosenunarchivoconextensinCSVLabel = uilabel(app.GuardardatosTab);
            app.NOTALosdatossonguardadosenunarchivoconextensinCSVLabel.Text = 'NOTA: Los datos son guardados en un archivo con extensión CSV';
            app.SeleccionarButton = uibutton(app.GuardardatosTab, 'push');
            app.SeleccionarButton.ButtonPushedFcn = createCallbackFcn(app, @SeleccionarButtonPushed, true);
            app.SeleccionarButton.Text = 'Seleccionar';
            app.RunButton = uibutton(app.PnduloInvertidoUIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Tag = 'btn_run';
            app.RunButton.Text = 'Run';
            % Show the figure after all components are created
            app.PnduloInvertidoUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = prueba

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PnduloInvertidoUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PnduloInvertidoUIFigure)
        end
    end
end